classdef msgbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        msgContent  matlab.ui.control.Label
        OKButton    matlab.ui.control.Button
        msgTitle    matlab.ui.control.Label
    end

    
    properties (Access = private)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, content, varargin)
            mIp = inputParser;
            mIp.addRequired("content", @(x) isstring(x) || ischar(x) || iscell(x) && all(cellfun(@(y) isstring(x) || ischar(y), x)));
            mIp.addOptional("titleStr", "Info", @(x) isstring(x) || ischar(x));
            mIp.addOptional("alignment0", [], @(x) any(validatestring(x, {'center', 'top-center', 'top-left', 'top-right', ...
                                                                          'center-left', 'center-right', ...
                                                                          'bottom-left', 'bottom-center', 'bottom-right'})));
            mIp.addParameter("Alignment", 'center-top', @(x) any(validatestring(x, {'left-top', 'center-top', 'right-top', ...
                                                                                    'left-center', 'center', 'right-center', ...
                                                                                    'left-bottom', 'center-bottom', 'right-bottom'})));
            mIp.addParameter("TitleColor", "r");
            mIp.addParameter("ContentColor", "k");
            mIp.addParameter("TitleFontSize", 20, @(x) validateattributes(x, {'numeric'}, {'scalar', 'positive'}));
            mIp.addParameter("ContentFontSize", 16, @(x) validateattributes(x, {'numeric'}, {'scalar', 'positive'}));
            mIp.addParameter("BackgroundColor", [0.94, 0.94, 0.94]);
            mIp.parse(content, varargin{:});

            titleStr = mIp.Results.titleStr;
            alignment = mu.getor(mIp.Results, "alignment0", mIp.Results.Alignment, true);
            titleColor = validatecolor(mIp.Results.TitleColor);
            contentColor = validatecolor(mIp.Results.ContentColor);
            titleFontSize = mIp.Results.TitleFontSize;
            contentFontSize = mIp.Results.ContentFontSize;
            backgroundColor = validatecolor(mIp.Results.BackgroundColor);

            if strcmpi(alignment, "center")
                hAlign = "center";
                vAlign = "center";
            else
                temp = split(alignment, '-');
                hAlign = temp{1};
                vAlign = temp{2};
            end

            app.UIFigure.Color = backgroundColor;

            app.msgTitle.Text = titleStr;
            app.msgTitle.FontColor = titleColor;
            app.msgTitle.FontSize = titleFontSize;
            app.msgContent.Text = content;
            app.msgContent.FontColor = contentColor;
            app.msgContent.FontSize = contentFontSize;
            app.msgContent.HorizontalAlignment = hAlign;
            app.msgContent.VerticalAlignment = vAlign;

            srceenSize = get(0, "screensize");
            figSize = app.UIFigure.Position;
            figSize(1) = (srceenSize(3) - figSize(3)) / 2;
            figSize(2) = (srceenSize(4) - figSize(4)) / 2;
            app.UIFigure.Position = figSize;

            uiwait(app.UIFigure);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            uiresume(app.UIFigure);
            delete(app);
        end

        % Callback function: UIFigure, UIFigure
        function UIFigureKeyPress(app, event)
            if strcmpi(event.EventName, 'Close') || ~isempty(event.Key)
                uiresume(app.UIFigure);
                delete(app);
            end
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            if strcmpi(event.EventName, 'Close') || ~isempty(event.Key)
                uiresume(app.UIFigure);
                delete(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.msgTitle = uilabel(app.UIFigure);
            app.msgTitle.Text = 'Title';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.msgContent = uilabel(app.UIFigure);
            app.msgContent.Text = 'Content';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = msgbox(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end