classdef checklist < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        PleaseselectLabel  matlab.ui.control.Label
        CancelButton       matlab.ui.control.Button
        OKButton           matlab.ui.control.Button
        Tree               matlab.ui.container.CheckBoxTree
    end


    properties (Access = public)
        treeItem     TreeItem
        selectedData struct
    end

    methods (Access = private)

        function buildTreeFromClass(app, rootNode)
            % buildTreeFromClass  Populate app.Tree from TreeNode class instances.
            %   rootNode : TreeNode (root of your logical tree)
            %              Refer to class TreeItem.m
            tree = app.Tree;

            if ~isa(rootNode, "TreeItem") || ~isscalar(rootNode)
                error('rootNode must be a scalar TreeItem.');
            end

            uiRoot = uitreenode(tree, "Text", char(rootNode.Text), "NodeData", rootNode.Data);
            app.addChildrenRecursive(uiRoot, rootNode, 1, inf);
        end

        function addChildrenRecursive(app, parentUINode, parentItem, depth, maxDepth)
            if depth >= maxDepth
                uitreenode(parentUINode, ...
                    "Text", sprintf("[MaxDepth=%d reached]", maxDepth), ...
                    "NodeData", depth);
                return;
            end

            ch = parentItem.Children;
            if isempty(ch)
                return;
            end

            for k = 1:numel(ch)
                c = ch(k);
                uiChild = uitreenode( ...
                    parentUINode, ...
                    "Text", char(c.Text), ...
                    "NodeData", c.Data);
                app.addChildrenRecursive(uiChild, c, depth + 1, maxDepth);
            end
        end

        function expandAllNodes(app, uiNode)
            % Recursively expand all UI tree nodes (cross-version safe)
            try
                expand(uiNode);
            catch
            end
            for k = 1:numel(uiNode.Children)
                app.expandAllNodes(uiNode.Children(k));
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, treeItem)
            % use uiwait(app.UIFigure) for ui control

            srceenSize = get(0, "screensize");
            figSize = app.UIFigure.Position;
            figSize(1) = (srceenSize(3) - figSize(3)) / 2;
            figSize(2) = (srceenSize(4) - figSize(4)) / 2;
            app.UIFigure.Position = figSize;

            app.treeItem = treeItem;
            app.buildTreeFromClass(treeItem);
            app.expandAllNodes(app.Tree);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            uiresume(app.UIFigure);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            checked = app.Tree.CheckedNodes(:);
            app.selectedData = struct("Text", {checked.Text}', "Data", {checked.NodeData}');
            uiresume(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Check list';
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.PleaseselectLabel = uilabel(app.UIFigure);
            app.PleaseselectLabel.Text = 'Please select';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = checklist(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end