classdef scaleAxesApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ScaleAxesPanel              matlab.ui.container.Panel
        LineButton                  matlab.ui.control.Button
        ResetButton                 matlab.ui.control.Button
        BestButton                  matlab.ui.control.Button
        SymmetricDropDown           matlab.ui.control.DropDown
        SymmetricDropDownLabel      matlab.ui.control.Label
        SyncSwitch                  matlab.ui.control.Switch
        ApplyButton                 matlab.ui.control.Button
        SyncLamp                    matlab.ui.control.Lamp
        SyncSwitchLabel             matlab.ui.control.Label
        CurrentrangeEditField       matlab.ui.control.EditField
        UpperEditField              matlab.ui.control.NumericEditField
        LowerEditField              matlab.ui.control.NumericEditField
        CurrentrangeEditFieldLabel  matlab.ui.control.Label
        SliderHTML                  matlab.ui.control.HTML
        AxistypeDropDown            matlab.ui.control.DropDown
        AxistypeDropDownLabel       matlab.ui.control.Label
    end


    properties (Access = public)
        target
        axisName
        limits
        currentRange
        htmlComponent
        syncOpt = "On"

        axisName0
        limits0
        currentRange0

        warningState
    end
    
    methods (Access = public)
        
        function updateCurrentRange(app)
            app.CurrentrangeEditField.Value = join(string(num2str(roundn(app.currentRange, -2))), '     ');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, target, axisName, currentRange, limits)
            app.UIFigure.Name = 'UI for scaleAxes';
            srceenSize = get(0, "screensize");
            figSize = app.UIFigure.Position;
            figSize(1) = (srceenSize(3) - figSize(3)) / 2;
            figSize(2) = (srceenSize(4) - figSize(4)) / 2;
            app.UIFigure.Position = figSize;

            app.warningState = warning().state;
            warning off;

            app.target = target;
            app.axisName = axisName;
            app.currentRange = currentRange;
            app.limits = limits;

            app.axisName0 = axisName;
            app.limits0 = limits;
            app.currentRange0 = currentRange;

            data.currentRange = currentRange;
            data.min = limits(1);
            data.max = limits(2);

            if limits(1) < 0
                data.ticks = sort(unique([0, currentRange]), 'ascend');
            else
                data.ticks = currentRange;
            end

            app.SliderHTML.HTMLSource = fullfile(mu.getrootpath(fileparts(mfilename("fullpath")), 1), 'resources\scaleAxes UI.html');
            app.SliderHTML.DataChangedFcn = {@htmlDataChangedFcn, app};
            app.SliderHTML.Data = data;
            app.htmlComponent = app.SliderHTML;

            app.LowerEditField.Value = limits(1);
            app.UpperEditField.Value = limits(2);

            app.AxistypeDropDown.Value = axisName;
            app.SyncLamp.Color = [0, 1, 0]; % On
            app.SyncSwitch.Value = "On";
            
            updateCurrentRange(app);

            % register DeleteFcn for target
            temp = get(target, "UserData");
            if numel(target) > 1
                for tIndex = 1:numel(target)
                    if isfield(temp{tIndex}, "apps")
                        temp{tIndex}.apps = [reshape(temp{tIndex}.apps, [numel(temp{tIndex}.apps), 1]); {app}];
                    else
                        temp{tIndex}.apps = {app};
                    end
                    target(tIndex).UserData = temp{tIndex};
                    set(target(tIndex), "DeleteFcn", @onTargetDeleteFcn);
                end
            else
                if isfield(temp, "apps")
                    temp.apps = [reshape(temp.apps, [numel(temp.apps), 1]); {app}];
                else
                    temp.apps = {app};
                end
                target.UserData = temp;
                set(target, "DeleteFcn", @onTargetDeleteFcn);
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            mu.scaleAxes(app.target, app.axisName, app.currentRange, "symOpt", app.SymmetricDropDown.Value);
            updateCurrentRange(app);
        end

        % Value changed function: SyncSwitch
        function SyncSwitchValueChanged(app, event)
            app.syncOpt = app.SyncSwitch.Value;

            if strcmp(app.syncOpt, "On")
                app.SyncLamp.Color = [0, 1, 0];
            else
                app.SyncLamp.Color = [0.8, 0.8, 0.8];
            end
        end

        % Value changed function: LowerEditField
        function LowerEditFieldValueChanged(app, event)
            app.limits(1) = app.LowerEditField.Value;
            app.htmlComponent.Data.min = app.limits(1);

            if app.currentRange(1) < app.limits(1)
                app.currentRange(1) = app.limits(1);
                updateCurrentRange(app);
            end

            if strcmp(app.syncOpt, "On")
                mu.scaleAxes(app.target, app.axisName, app.currentRange, "symOpt", app.SymmetricDropDown.Value);
                updateCurrentRange(app);
            end
        end

        % Value changed function: UpperEditField
        function UpperEditFieldValueChanged(app, event)
            app.limits(2) = app.UpperEditField.Value;
            app.htmlComponent.Data.max = app.limits(2);

            if app.currentRange(2) > app.limits(2)
                app.currentRange(2) = app.limits(2);
                updateCurrentRange(app);
            end

            if strcmp(app.syncOpt, "On")
                mu.scaleAxes(app.target, app.axisName, app.currentRange, "symOpt", app.SymmetricDropDown.Value);
                updateCurrentRange(app);
            end
        end

        % Value changed function: CurrentrangeEditField
        function CurrentrangeEditFieldValueChanged(app, event)
            app.currentRange = eval(strcat("[", app.CurrentrangeEditField.Value, "]"));
            data = app.htmlComponent.Data;
            data.currentRange = app.currentRange;
            if app.currentRange(1) < app.limits(1) || app.currentRange(2) > app.limits(2)
                app.limits = [app.currentRange(1) - 0.25 * diff(app.currentRange), ...
                              app.currentRange(2) + 0.25 * diff(app.currentRange)];
                app.LowerEditField.Value = app.limits(1);
                app.UpperEditField.Value = app.limits(2);
                data.min = app.limits(1);
                data.max = app.limits(2);
            end
            app.htmlComponent.Data = data;
            mu.scaleAxes(app.target, app.axisName, app.currentRange, "symOpt", app.SymmetricDropDown.Value);
            updateCurrentRange(app);
        end

        % Value changed function: AxistypeDropDown
        function AxistypeDropDownValueChanged(app, event)
            app.axisName = app.AxistypeDropDown.Value;
            app.currentRange = mu.scaleAxes(app.target, app.axisName, "symOpt", app.SymmetricDropDown.Value);
            app.limits = [app.currentRange(1) - 0.25 * diff(app.currentRange), ...
                          app.currentRange(2) + 0.25 * diff(app.currentRange)];
            data.currentRange = app.currentRange;
            data.min = app.limits(1);
            data.max = app.limits(2);
            data.ticks = sort([0, app.currentRange], 'ascend');
            app.htmlComponent.Data = data;
            app.LowerEditField.Value = app.limits(1);
            app.UpperEditField.Value = app.limits(2);
            updateCurrentRange(app);
        end

        % Button pushed function: BestButton
        function BestButtonPushed(app, event)
            app.currentRange = mu.scaleAxes(app.axisName, "on");
            app.limits = [app.currentRange(1) - 0.25 * diff(app.currentRange), ...
                          app.currentRange(2) + 0.25 * diff(app.currentRange)];
            data.currentRange = app.currentRange;
            data.min = app.limits(1);
            data.max = app.limits(2);
            data.ticks = sort([0, app.currentRange], 'ascend');
            app.htmlComponent.Data = data;
            app.LowerEditField.Value = app.limits(1);
            app.UpperEditField.Value = app.limits(2);

            mu.scaleAxes(app.target, app.axisName, app.currentRange, "symOpt", app.SymmetricDropDown.Value);
            updateCurrentRange(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.axisName = app.axisName0;
            app.currentRange = app.currentRange0;
            app.limits = app.limits0;
            app.AxistypeDropDown.Value = lower(app.axisName0);

            data.currentRange = app.currentRange;
            data.min = app.limits(1);
            data.max = app.limits(2);
            data.ticks = sort([0, app.currentRange], 'ascend');
            app.htmlComponent.Data = data;
            app.LowerEditField.Value = app.limits(1);
            app.UpperEditField.Value = app.limits(2);
            
            mu.scaleAxes(app.target, app.axisName, app.currentRange, "symOpt", app.SymmetricDropDown.Value);
            updateCurrentRange(app);
        end

        % Value changed function: SymmetricDropDown
        function SymmetricDropDownValueChanged(app, event)
            mu.scaleAxes(app.target, app.axisName, app.currentRange, "symOpt", app.SymmetricDropDown.Value);
            updateCurrentRange(app);
        end

        % Button pushed function: LineButton
        function LineButtonPushed(app, event)
            addLinesApp(app.target);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            warning(app.warningState);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ScaleAxesPanel = uipanel(app.UIFigure);
            app.ScaleAxesPanel.Title = 'Scale Axes Panel';
            app.AxistypeDropDownLabel = uilabel(app.ScaleAxesPanel);
            app.AxistypeDropDownLabel.Text = 'Axis type';
            app.AxistypeDropDown = uidropdown(app.ScaleAxesPanel);
            app.AxistypeDropDown.Items = {'x', 'y', 'z', 'c'};
            app.AxistypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AxistypeDropDownValueChanged, true);
            app.AxistypeDropDown.Value = 'x';
            app.SliderHTML = uihtml(app.ScaleAxesPanel);
            app.CurrentrangeEditFieldLabel = uilabel(app.ScaleAxesPanel);
            app.CurrentrangeEditFieldLabel.Text = 'Current range';
            app.LowerEditField = uieditfield(app.ScaleAxesPanel, 'numeric');
            app.LowerEditField.ValueChangedFcn = createCallbackFcn(app, @LowerEditFieldValueChanged, true);
            app.UpperEditField = uieditfield(app.ScaleAxesPanel, 'numeric');
            app.UpperEditField.ValueChangedFcn = createCallbackFcn(app, @UpperEditFieldValueChanged, true);
            app.UpperEditField.Value = 1;
            app.CurrentrangeEditField = uieditfield(app.ScaleAxesPanel, 'text');
            app.CurrentrangeEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentrangeEditFieldValueChanged, true);
            app.SyncSwitchLabel = uilabel(app.ScaleAxesPanel);
            app.SyncSwitchLabel.Text = 'Sync';
            app.SyncLamp = uilamp(app.ScaleAxesPanel);
            app.ApplyButton = uibutton(app.ScaleAxesPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.SyncSwitch = uiswitch(app.ScaleAxesPanel, 'slider');
            app.SyncSwitch.ValueChangedFcn = createCallbackFcn(app, @SyncSwitchValueChanged, true);
            app.SymmetricDropDownLabel = uilabel(app.ScaleAxesPanel);
            app.SymmetricDropDownLabel.Text = 'Symmetric';
            app.SymmetricDropDown = uidropdown(app.ScaleAxesPanel);
            app.SymmetricDropDown.Items = {'none', 'min', 'max', 'positive', 'negative'};
            app.SymmetricDropDown.ValueChangedFcn = createCallbackFcn(app, @SymmetricDropDownValueChanged, true);
            app.SymmetricDropDown.Value = 'none';
            app.BestButton = uibutton(app.ScaleAxesPanel, 'push');
            app.BestButton.ButtonPushedFcn = createCallbackFcn(app, @BestButtonPushed, true);
            app.BestButton.Text = 'Best';
            app.ResetButton = uibutton(app.ScaleAxesPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.LineButton = uibutton(app.ScaleAxesPanel, 'push');
            app.LineButton.ButtonPushedFcn = createCallbackFcn(app, @LineButtonPushed, true);
            app.LineButton.Text = 'Line';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scaleAxesApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end