classdef stopwatch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StopwatchUIFigure  matlab.ui.Figure
        Image              matlab.ui.control.Image
        markButton         matlab.ui.control.Button
        TimeLabel          matlab.ui.control.Label
        resetButton        matlab.ui.control.Button
        Monitor            matlab.ui.control.TextArea
        startstopButton    matlab.ui.control.Button
    end

    
    properties (Access = private)
        StopwatchTimer   timer           % for time label update only
        T0               uint64          % tic reference
        Elapsed          double = 0      % accumulated time
        LastPauseElapsed double          % last pause time (sec)
        ind              double = 0      % pause index
        head
    end
    
    properties (Access = public)
        timestamp
    end
    
    methods (Access = private)
        
        function updateStopwatch(app)
            if ~isempty(app.T0)
                t = app.Elapsed + toc(app.T0);
                minutes = floor(t / 60);
                seconds = floor(mod(t, 60));
                thousands = floor(mod(t, 1) * 1000);
                app.TimeLabel.Text = sprintf('%02d:%02d.%03d', minutes, seconds, thousands);
            end
        end

        function updateText(app)
            tPause = toc(app.T0);
            tElapsed = app.Elapsed + tPause;
            dt = tPause - app.LastPauseElapsed;
            app.LastPauseElapsed = tPause;
            app.ind = app.ind + 1;
            app.timestamp = [app.timestamp; tElapsed];
        
            line = sprintf('%s|%s|%s', ...
                           app.centerText(sprintf('%d',   app.ind),  20), ...
                           app.centerText(sprintf('%.3f', dt),       30), ...
                           app.centerText(sprintf('%.3f', tElapsed), 30));
            v = cellstr(app.Monitor.Value);
            app.Monitor.Value = [app.head; {line}; v(3:end)];
            idx = cellfun(@isempty, app.Monitor.Value);
            app.Monitor.Value = app.Monitor.Value(~idx);
            drawnow;
        end

        function s = centerText(~, txt, width)
            % Center a string in a fixed-width field using spaces
            
            txt = string(txt);
            n = strlength(txt);
            
            if n >= width
                s = extractBefore(txt, width+1);
            else
                padL = floor((width - n)/2);
                padR = width - n - padL;
                s = [repmat(' ',1,padL), char(txt), repmat(' ',1,padR)];
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            srceenSize = get(0, "screensize");
            figSize = app.StopwatchUIFigure.Position;
            figSize(1) = (srceenSize(3) - figSize(3)) / 2;
            figSize(2) = (srceenSize(4) - figSize(4)) / 2;
            app.StopwatchUIFigure.Position = figSize;
            app.StopwatchUIFigure.Color = [1, 1, 1];
            
            if isempty(app.StopwatchTimer) || ~isvalid(app.StopwatchTimer)
                app.StopwatchTimer = timer( ...
                    'ExecutionMode', 'fixedSpacing', ...
                    'Period', 0.03, ...           % ~33 Hz
                    'TimerFcn', @(~,~) app.updateStopwatch());
            end
            app.resetButton.Enable = "off";
            app.markButton.Enable = "off";
            app.head = sprintf('%s|%s|%s\n%s', ...
                               app.centerText('Index',          20), ...
                               app.centerText('Interval (sec)', 30), ...
                               app.centerText('Time (sec)',     30), ...
                               repmat('–', 1, 50));
            app.Monitor.Value = app.head;
        end

        % Button pushed function: startstopButton
        function startstopButtonPushed(app, event)
            if strcmp(app.StopwatchTimer.Running, "on") % stop timer
                stop(app.StopwatchTimer);
                app.Elapsed = app.Elapsed + toc(app.T0);
                app.startstopButton.Icon = fullfile(fileparts(mfilename("fullpath")), "resources", "icon_start.png");
                app.markButton.Enable = "off";
            else % start timer
                app.T0 = tic;
                start(app.StopwatchTimer);
                app.startstopButton.Icon = fullfile(fileparts(mfilename("fullpath")), "resources", "icon_stop.png");
                app.markButton.Enable = "on";
                app.resetButton.Enable = "on";
                app.LastPauseElapsed = 0;
            end
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            if ~isempty(app.StopwatchTimer) && isvalid(app.StopwatchTimer)
                stop(app.StopwatchTimer);
            end
        
            app.Elapsed = 0;
            app.T0 = [];
            app.ind = 0;
        
            app.Monitor.Value = app.head;
            app.TimeLabel.Text = "00:00.000";
            app.startstopButton.Enable = "on";
            app.markButton.Enable = "off";
            app.resetButton.Enable = "off";
            app.startstopButton.Icon = fullfile(fileparts(mfilename("fullpath")), "resources", "icon_start.png");
        end

        % Close request function: StopwatchUIFigure
        function StopwatchUIFigureCloseRequest(app, event)
            if ~isempty(app.StopwatchTimer) && isvalid(app.StopwatchTimer)
                stop(app.StopwatchTimer);
                delete(app.StopwatchTimer);
            end
            delete(app);
        end

        % Button pushed function: markButton
        function markButtonPushed(app, event)
            app.updateText();
        end

        % Window key press function: StopwatchUIFigure
        function StopwatchUIFigureWindowKeyPress(app, event)
            key = event.Key;
            switch key
                case 'space'
                    app.startstopButtonPushed();
                case {'leftarrow', 'rightarrow'}
                    app.markButtonPushed();
                case 'r'
                    app.resetButtonPushed();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.StopwatchUIFigure = uifigure('Visible', 'off');
            app.StopwatchUIFigure.Name = 'Stopwatch';
            app.StopwatchUIFigure.Icon = fullfile(pathToMLAPP, 'resources', 'icon.png');
            app.StopwatchUIFigure.CloseRequestFcn = createCallbackFcn(app, @StopwatchUIFigureCloseRequest, true);
            app.StopwatchUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @StopwatchUIFigureWindowKeyPress, true);
            app.startstopButton = uibutton(app.StopwatchUIFigure, 'push');
            app.startstopButton.ButtonPushedFcn = createCallbackFcn(app, @startstopButtonPushed, true);
            app.startstopButton.Icon = fullfile(pathToMLAPP, 'resources', 'icon_start.png');
            app.startstopButton.Text = '';
            app.Monitor = uitextarea(app.StopwatchUIFigure);
            app.Monitor.Editable = 'off';
            app.resetButton = uibutton(app.StopwatchUIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Icon = fullfile(pathToMLAPP, 'resources', 'icon_reset.png');
            app.resetButton.Text = '';
            app.TimeLabel = uilabel(app.StopwatchUIFigure);
            app.TimeLabel.Text = '00:00.000';
            app.markButton = uibutton(app.StopwatchUIFigure, 'push');
            app.markButton.ButtonPushedFcn = createCallbackFcn(app, @markButtonPushed, true);
            app.markButton.Icon = fullfile(pathToMLAPP, 'resources', 'icon_mark.png');
            app.markButton.Text = '';
            app.Image = uiimage(app.StopwatchUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'resources', 'icon_large.png');
            % Show the figure after all components are created
            app.StopwatchUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stopwatch

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StopwatchUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StopwatchUIFigure)
        end
    end
end