classdef validateinputApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        InputValidationPanel  matlab.ui.container.Panel
        TextArea              matlab.ui.control.TextArea
        OKButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        validateFcn
        sInput
    end
    
    properties (Access = public)
        res
    end
    
    methods (Access = private)
        
        function res = validateVal(app, val)
            if app.sInput
                val = val{1};
            else
                try
                    val = eval(val{1});
                catch
                    res = false;
                    return;
                end
            end

            if ~isempty(app.validateFcn)
                try
                    res = app.validateFcn(val);
                catch ME
                    if ~strcmp(ME.identifier, 'MATLAB:maxlhs')
                        try
                            app.validateFcn(val);
                            res = true;
                        catch
                            res = false;
                        end
                    end
                end
            else
                res = true;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, prompt, validateFcn, sInput)
            app.TextArea.Placeholder = prompt;
            app.TextArea.FontColor = [150, 150, 150] / 255; % placeholder color
            app.validateFcn = validateFcn;

            if ~isempty(sInput)
                app.sInput = true;
            else
                app.sInput = false;
            end

            if app.validateVal(app.TextArea.Value)
                app.OKButton.Enable = "on";
            else
                app.OKButton.Enable = "off";
            end

            srceenSize = get(0, "screensize");
            figSize = app.UIFigure.Position;
            figSize(1) = (srceenSize(3) - figSize(3)) / 2;
            figSize(2) = (srceenSize(4) - figSize(4)) / 2;
            app.UIFigure.Position = figSize;

            uiwait(app.UIFigure);
        end

        % Value changing function: TextArea
        function TextAreaValueChanging(app, event)
            changingValue = event.Value;
            if isempty(changingValue{1})
                app.TextArea.FontColor = [150, 150, 150] / 255; % placeholder color
            else
                app.TextArea.FontColor = [0, 0, 0];
            end
            if app.validateVal(changingValue)
                app.OKButton.Enable = "on";
            else
                app.OKButton.Enable = "off";
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if app.sInput
                app.res = app.TextArea.Value{1};
            else
                app.res = eval(app.TextArea.Value{1});
            end
            uiresume(app.UIFigure);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Prevent from closing
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)

        end

        % Callback function: UIFigure, UIFigure
        function UIFigureWindowKeyRelease(app, event)

        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureWindowKeyRelease, true);
            app.UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @UIFigureWindowKeyRelease, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.InputValidationPanel = uipanel(app.UIFigure);
            app.InputValidationPanel.Title = 'Input Validation';
            app.OKButton = uibutton(app.InputValidationPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.TextArea = uitextarea(app.InputValidationPanel);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.TextArea.ValueChangingFcn = createCallbackFcn(app, @TextAreaValueChanging, true);
            app.TextArea.Placeholder = 'prompt';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = validateinputApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end